classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CSUNAntennaMeasurementAppUIFigure  matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        QuitMenu                matlab.ui.container.Menu
        ToolsMenu               matlab.ui.container.Menu
        MotionControllerMenu    matlab.ui.container.Menu
        VNAMenu                 matlab.ui.container.Menu
        PrologixConfigMenu      matlab.ui.container.Menu
        ProjectMenu             matlab.ui.container.Menu
        ManageProjectsMenu      matlab.ui.container.Menu
        ViewResultsButton       matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        NoProjectSelectedLabel  matlab.ui.control.Label
        ProjectLabel            matlab.ui.control.Label
        ManageJobsButton        matlab.ui.control.Button
        CameraButton            matlab.ui.control.Button
        RunJobsButton           matlab.ui.control.Button
    end


    properties (Access = private)
        MotionControllerApp % Reference to MotionControllerApp dialogue
        ManageJobs % Reference to ManageJobs dialogue
        RunJobs % Reference to RunJobs dialogue
        VNAApp  % Reference to VNA dialogue
        ManageProjects
    end

    properties (Access = public)
        Bus
        Config
        MotionControlLogger % Public so other windows can access it.
        MotionController
        VNA
        CameraFeed
        ViewResults
        ProjectPath %temp until I find a better way to do this.
    end

    methods (Access = private)
        function newBusLogEvent(app, msg, logs)
            if ~isempty(app.Config)
                app.Config.onLogEvent(msg, logs);
            end
        end

        function newMotionControlLogEvent(app, msg, logs)
            %
            % TODO: There is a known bug where if you open, then close,
            % then open the motion controller app, an error occurs related
            % to logging callbacks. This is supposed to solve it, but
            % doesn't. Checking isvalid() or isobject() doesn't work either.
            % And if I set app.MotionControllerApp to something like false
            % after it's deleted, I get other errors.
            % What's actually going wrong?
            %
            if ~isempty(app.MotionControllerApp)
                app.MotionControllerApp.onLogEvent(msg, logs);
            end
        end
        
        function filename = getJobsFileName(app)
            workFolder = app.getWorkingFolder();
            filename = sprintf("%s\\%s", workFolder, "jobs.mat");
        end
        
    end

    methods (Access = public)
        function closingMotionControlApp(app)
            % called from MotionControlApp when it's being closed
            app.MotionControllerMenu.Enable = 'on';
            delete(app.MotionControllerApp);
        end

        function closingManageJobs(app)
            % called from ManageJobs dialogue when it's being closed
            app.ManageJobsButton.Enable = 'on';
            delete(app.ManageJobs);
        end

        function closingRunJobs(app)
            % called from RunJobs dialogue when it's being closed
            try
                app.RunJobsButton.Enable = 'on';
                delete(app.RunJobs);
            catch e
                % don't complain? otherwise the window won't close.
            end
        end

        function closingConfigApp(app)
            % called from Config dialogue when it's being closed
            app.PrologixConfigMenu.Enable = 'on';
            delete(app.Config);
        end

        function closingVNAApp(app)
            % called from VNAApp dialogue when it's being closed
            app.VNAMenu.Enable = 'on';
            delete(app.VNAApp);
        end

        function f = getWorkingFolder(app)
            % This is a bit specific to windows, but that's fine for the
            % moment. This can be improved when support for individual
            % projects is added.

            % %OLD
            % % Get user home folder
            % userProfile = getenv("USERPROFILE");
            % % "My Documents" folder for current user:
            % myDocsFolder = sprintf("%s\\Documents\\Antenna Measurements", userProfile);
            % f = myDocsFolder;

            %NEW
            f = app.ProjectPath;
        end
        
        function j = getJob(app, jobName)
            % jobName is a cell array containing only a single stringcl

            % This is the default for a new job
            j = struct();
            j.version = 1;
            j.name = {"New job"}; % Note that the UI overrides this
            j.sweepMode = "bidirectional";
            axis1.enable = "On";
            axis1.start = 0;
            axis1.stop = 360;
            axis1.increment = 10;
            axis2.enable = "Off";
            axis2.start = 0;
            axis2.stop = 0;
            axis2.increment = 0;
            axis3.enable = "Off";
            axis3.start = 0;
            axis3.stop = 0;
            axis3.increment = 0;
            j.axes = [axis1, axis2, axis3];
            j.startFreq = 1.0e9;
            j.stopFreq = 3.0e9;
            j.numPts = 201;

            if (isfile(app.getJobsFileName()))
                % load jobs variable
                load(app.getJobsFileName());

                % Find existing job with matching name, if exists
                for idx = 1:width(jobs)
                    job = jobs(idx);
                    if strcmp(job.name{1}, jobName{1})
                        j = job;
                        break;
                    end
                end
            end
        end
        
        function saveJob(app, job)
            if (isfile(app.getJobsFileName()))
                % load jobs variable
                load(app.getJobsFileName(), "jobs");

                % Find existing job with matching name, if exists
                match = false;
                for idx = 1:width(jobs)
                    j = jobs(idx);
                    if strcmp(j.name{1}, job.name{1})
                        match = idx;
                        break;
                    end
                end

                if match
                    jobs(match) = job;
                else
                    jobs(end + 1) = job;
                end
            else
                jobs(1) = job;
            end

            save(app.getJobsFileName(), "jobs");
        end

        function destroyJob(app, job)
            % the file must exist if we're trying to destroy a job,
            % so we don't need to check if it exists.
            load(app.getJobsFileName(), "jobs");

            for idx = 1:width(jobs)
                j = jobs(idx);
                if strcmp(j.name{1}, job{1})
                    % Get rid of this job
                    jobs(idx) = [];
                    break;
                end
            end

            save(app.getJobsFileName(), "jobs");
        end

        function names = getJobNames(app)
            if (isfile(app.getJobsFileName()))
                load(app.getJobsFileName(), "jobs");
                names = [jobs.name];
            else
                names = [""];
            end
        end
        
        function updateProject(app,name,path)
            if isempty(name) == 1
                app.ManageJobsButton.Enable = "off";
                app.RunJobsButton.Enable = "off";
            else
                app.ManageJobsButton.Enable = "on";
                app.RunJobsButton.Enable = "on";
                app.NoProjectSelectedLabel.Text = name;
                app.ProjectPath = path; %im too dumb to figure out a way to do this without properties
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [bus, m, vna, buslog, motlog, vnalog] = bootstrap();
            % bus is the GPIBBus
            % m is the MotionController
            % vna is the VNA
            % buslog is a log for the bus
            % motlog is a log for the motion controller
            % vnalog is a log for the vna
            %
            % Note that there is a fair amount of overlap between these
            % various logs. You usually don't really need the buslog, but
            % it's here in case you need it for debugging.

            % Make things accessible to sub-apps
            app.Bus = bus;
            app.MotionController = m;
            app.VNA = vna;

            % Set up logging for gpib bus
            buslog.SetCallback(@(m, l) app.newBusLogEvent(m, l));

            % Set up logging for motion controller
            app.MotionControlLogger = motlog;
            app.MotionControlLogger.SetCallback(@(m, l) app.newMotionControlLogEvent(m, l));

            % Set up logging for vna
% TODO TODO TODO

            % Disable Manage Jobs and Run Jobs Buttons until project is
            % selected
            app.ManageJobsButton.Enable = "off";
            app.RunJobsButton.Enable = "off";
        end

        % Menu selected function: MotionControllerMenu
        function MotionControllerMenuSelected(app, event)
            app.MotionControllerApp = MotionControllerApp(app);
            app.MotionControllerMenu.Enable = 'off';
        end

        % Close request function: CSUNAntennaMeasurementAppUIFigure
        function CSUNAntennaMeasurementAppUIFigureCloseRequest(app, event)
            % Close all subwindows, then the main app.
            delete(app.MotionControllerApp);
            delete(app.ManageJobs);
            delete(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            app.MotionControlLogger.Info("asdfadsf");
        end

        % Button pushed function: ManageJobsButton
        function ManageJobsButtonPushed(app, event)
            app.ManageJobs = ManageJobs(app);
            app.ManageJobsButton.Enable = 'off';
        end

        % Button pushed function: RunJobsButton
        function RunJobsButtonPushed(app, event)
            app.RunJobs = RunJobs(app);
            app.RunJobsButton.Enable = 'off';
        end

        % Button pushed function: CameraButton
        function CameraButtonPushed(app, event)
            app.CameraFeed = CameraFeed();
            app.CameraButton.Enable = 'on';
        end

        % Menu selected function: PrologixConfigMenu
        function PrologixConfigMenuSelected(app, event)
            app.Config = Config(app);
            app.PrologixConfigMenu.Enable = 'off';
        end

        % Menu selected function: VNAMenu
        function VNAMenuSelected(app, event)
            app.VNAApp = VNAApp(app);
            app.VNAMenu.Enable = 'off';
        end

        % Button pushed function: ViewResultsButton
        function ViewResultsButtonPushed(app, event)
            app.ViewResults = ViewResults(); %#ok<ADPROPLC>
        end

        % Menu selected function: ManageProjectsMenu
        function ManageProjectsMenuSelected(app, event)
            app.ManageProjects = ManageProjects(app); %#ok<ADPROPLC>
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CSUNAntennaMeasurementAppUIFigure = uifigure('Visible', 'off');
            app.CSUNAntennaMeasurementAppUIFigure.Name = 'CSUN Antenna Measurement App';
            app.CSUNAntennaMeasurementAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @CSUNAntennaMeasurementAppUIFigureCloseRequest, true);
            app.Menu = uimenu(app.CSUNAntennaMeasurementAppUIFigure);
            app.Menu.Text = 'Menu';
            app.QuitMenu = uimenu(app.Menu);
            app.QuitMenu.Text = 'Quit';
            app.ToolsMenu = uimenu(app.CSUNAntennaMeasurementAppUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.MotionControllerMenu = uimenu(app.ToolsMenu);
            app.MotionControllerMenu.MenuSelectedFcn = createCallbackFcn(app, @MotionControllerMenuSelected, true);
            app.MotionControllerMenu.Text = 'Motion Controller';
            app.VNAMenu = uimenu(app.ToolsMenu);
            app.VNAMenu.MenuSelectedFcn = createCallbackFcn(app, @VNAMenuSelected, true);
            app.VNAMenu.Text = 'VNA';
            app.PrologixConfigMenu = uimenu(app.ToolsMenu);
            app.PrologixConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @PrologixConfigMenuSelected, true);
            app.PrologixConfigMenu.Text = 'Prologix Config';
            app.ProjectMenu = uimenu(app.CSUNAntennaMeasurementAppUIFigure);
            app.ProjectMenu.Text = 'Project';
            app.ManageProjectsMenu = uimenu(app.ProjectMenu);
            app.ManageProjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @ManageProjectsMenuSelected, true);
            app.ManageProjectsMenu.Text = 'Manage Projects';
            app.RunJobsButton = uibutton(app.CSUNAntennaMeasurementAppUIFigure, 'push');
            app.RunJobsButton.ButtonPushedFcn = createCallbackFcn(app, @RunJobsButtonPushed, true);
            app.RunJobsButton.Tooltip = {'Run measurement jobs'};
            app.RunJobsButton.Text = 'Run Jobs';
            app.CameraButton = uibutton(app.CSUNAntennaMeasurementAppUIFigure, 'push');
            app.CameraButton.ButtonPushedFcn = createCallbackFcn(app, @CameraButtonPushed, true);
            app.CameraButton.Tooltip = {'Open the camera view'};
            app.CameraButton.Text = 'Camera';
            app.ManageJobsButton = uibutton(app.CSUNAntennaMeasurementAppUIFigure, 'push');
            app.ManageJobsButton.ButtonPushedFcn = createCallbackFcn(app, @ManageJobsButtonPushed, true);
            app.ManageJobsButton.Tooltip = {'Configure measurement jobs'};
            app.ManageJobsButton.Text = 'Manage Jobs';
            app.ProjectLabel = uilabel(app.CSUNAntennaMeasurementAppUIFigure);
            app.ProjectLabel.Text = 'Project:';
            app.NoProjectSelectedLabel = uilabel(app.CSUNAntennaMeasurementAppUIFigure);
            app.NoProjectSelectedLabel.Text = '--No Project Selected--';
            app.Image = uiimage(app.CSUNAntennaMeasurementAppUIFigure);
            app.Image.ImageSource = 'CECS.png';
            app.ViewResultsButton = uibutton(app.CSUNAntennaMeasurementAppUIFigure, 'push');
            app.ViewResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewResultsButtonPushed, true);
            app.ViewResultsButton.Tooltip = {'View the Results'};
            app.ViewResultsButton.Text = {'View Results'; ''};
            % Show the figure after all components are created
            app.CSUNAntennaMeasurementAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CSUNAntennaMeasurementAppUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.CSUNAntennaMeasurementAppUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CSUNAntennaMeasurementAppUIFigure)
        end
    end
end