classdef test2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        SetpositionButton       matlab.ui.control.Button
        PositionEditField       matlab.ui.control.NumericEditField
        PositionEditFieldLabel  matlab.ui.control.Label
        NofaultButton           matlab.ui.control.Button
        FaultButton             matlab.ui.control.Button
        StoppedButton           matlab.ui.control.Button
        MovingButton            matlab.ui.control.Button
        MotionControllerAxis    MotionControllerAxis
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MovingButton
        function MovingButtonPushed(app, event)
            app.MotionControllerAxis.Moving = true;
        end

        % Button pushed function: StoppedButton
        function StoppedButtonPushed(app, event)
            app.MotionControllerAxis.Moving = false;
        end

        % Button pushed function: FaultButton
        function FaultButtonPushed(app, event)
            app.MotionControllerAxis.Fault = true;
        end

        % Button pushed function: NofaultButton
        function NofaultButtonPushed(app, event)
            app.MotionControllerAxis.Fault = false;
        end

        % Button pushed function: SetpositionButton
        function SetpositionButtonPushed(app, event)
            app.MotionControllerAxis.AxisPosition = app.PositionEditField.Value;
        end

        % Callback function: MotionControllerAxis
        function MotionControllerAxisMoveToBtnPushed(app, event)
            pos = app.MotionControllerAxis.TargetPosition;
            msgbox("Move to " + pos);
        end

        % Callback function: MotionControllerAxis
        function MotionControllerAxisPlusFiveBtnPushed(app, event)
            msgbox("Plus five");
        end

        % Callback function: MotionControllerAxis
        function MotionControllerAxisMinusFiveBtnPushed(app, event)
            msgbox("Minus five");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MotionControllerAxis = MotionControllerAxis(app.GridLayout);
            app.MotionControllerAxis.MoveToBtnPushedFcn = createCallbackFcn(app, @MotionControllerAxisMoveToBtnPushed, true);
            app.MotionControllerAxis.PlusFiveBtnPushedFcn = createCallbackFcn(app, @MotionControllerAxisPlusFiveBtnPushed, true);
            app.MotionControllerAxis.MinusFiveBtnPushedFcn = createCallbackFcn(app, @MotionControllerAxisMinusFiveBtnPushed, true);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.MovingButton = uibutton(app.GridLayout2, 'push');
            app.MovingButton.ButtonPushedFcn = createCallbackFcn(app, @MovingButtonPushed, true);
            app.MovingButton.Text = 'Moving';
            app.StoppedButton = uibutton(app.GridLayout2, 'push');
            app.StoppedButton.ButtonPushedFcn = createCallbackFcn(app, @StoppedButtonPushed, true);
            app.StoppedButton.Text = 'Stopped';
            app.FaultButton = uibutton(app.GridLayout2, 'push');
            app.FaultButton.ButtonPushedFcn = createCallbackFcn(app, @FaultButtonPushed, true);
            app.FaultButton.Text = 'Fault';
            app.NofaultButton = uibutton(app.GridLayout2, 'push');
            app.NofaultButton.ButtonPushedFcn = createCallbackFcn(app, @NofaultButtonPushed, true);
            app.NofaultButton.Text = 'No fault';
            app.PositionEditFieldLabel = uilabel(app.GridLayout2);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SetpositionButton = uibutton(app.GridLayout2, 'push');
            app.SetpositionButton.ButtonPushedFcn = createCallbackFcn(app, @SetpositionButtonPushed, true);
            app.SetpositionButton.Text = 'Set position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end