classdef CameraFeed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CSUNAnechoicChamberLiveFeedUIFigure  matlab.ui.Figure
        UIAxes  matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
    function startCam(app)
        anechoicCam = ipcam('rtsp://anechoic.csun.edu:554/streaming/channels/3/rtsppreview', 'admin', 'CSUNanechoic','Timeout',600);
        w = 640;
        h = 360;
        hImage = image(app.UIAxes,zeros(h,w,3));
        app.UIAxes.XLim = [0,w];
        app.UIAxes.YLim = [0,h];
        app.UIAxes.XTick = [];
        app.UIAxes.YTick = [];
        pbaspect(app.UIAxes,[w,h,1]);
        app.UIAxes.Position = [0 0 w h];

        setappdata(hImage, 'UpdatePreviewWindowFcn', @mypreview_fcn);

        preview(anechoicCam,hImage);

        function mypreview_fcn(obj,event,himage)
            % Example update preview window function.
            
            % Get timestamp for frame.
            tstampstr = event.Timestamp;
            
            % Get handle to text label uicontrol.
            ht = getappdata(himage,'HandleToTimestampLabel');

            % Set the value of the text label.
            ht.String = tstampstr;
            
            % Display image data.
            himage.CData = event.Data;
        end
    end
        
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startCam(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CSUNAnechoicChamberLiveFeedUIFigure = uifigure('Visible', 'off');
            app.CSUNAnechoicChamberLiveFeedUIFigure.Name = 'CSUN Anechoic Chamber Live Feed';
            app.UIAxes = uiaxes(app.CSUNAnechoicChamberLiveFeedUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.CSUNAnechoicChamberLiveFeedUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CameraFeed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CSUNAnechoicChamberLiveFeedUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CSUNAnechoicChamberLiveFeedUIFigure)
        end
    end
end