classdef Config < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LogBox                   matlab.ui.control.TextArea
        TODOAlsoaddmotioncontrollerandVNAgpibaddresseshereLabel  matlab.ui.control.Label
        ConnectButton            matlab.ui.control.Button
        ConnectedLampLabel       matlab.ui.control.Label
        ConnectedLamp            matlab.ui.control.Lamp
        RefreshButton            matlab.ui.control.Button
        SerialportDropDownLabel  matlab.ui.control.Label
        SerialportDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        CallingApp % Reference back to the main app
    end
    
    methods (Access = private)
        function refreshSerialPortList(app)
            app.SerialportDropDown.Items = seriallist();
        end

        function updateConnectedLampDisplay(app, state)
            % true means connected, false means disconnected
            if state
                app.ConnectedLamp.Color = 'green';
                app.ConnectButton.Enable = false;
            else
                app.ConnectedLamp.Color = 'red';
                app.ConnectButton.Enable = true;
            end
        end

        function onConnectStateChange(app, state)
            % TODO: Probably just move updateConnectedLampDisplay() into
            % here.
            app.updateConnectedLampDisplay(state);
        end
    end
    
    methods (Access = public)
        function onLogEvent(app, msg, logs)
            if ~isempty(app.LogBox)
                try
                    if msg == ""
                        app.LogBox.Value = logs;
                    else
                        % Add an extra newline due to a bug in scroll().
                        % I left a comment about it here:
                        % https://www.mathworks.com/matlabcentral/answers/351131-how-do-i-make-a-full-text-area-scroll-up-when-i-add-more-text
                        app.LogBox.Value = strcat(logs, {newline});
    
                        % I tried only appending the new data,
                        % but nothing seems to make matlab happy.
                        % https://www.mathworks.com/matlabcentral/answers/747777-why-n-or-newline-doesn-t-work?s_tid=answers_rc1-2_p2_MLT
                        %app.LogBox.Value = strcat(app.LogBox.Value, {newline}, "asdf");
                        %app.LogBox.Value = strcat(app.LogBox.Value, msg, {newline});
                    end
    
                    scroll(app.LogBox, 'bottom');
                catch e
                    warning("Error in Config::onLogEvent");
                    disp(e);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;

            app.refreshSerialPortList();

            app.CallingApp.Bus.setOnConnectStateChangeCallback(@(s) app.onConnectStateChange(s));

            % Get connection state
            app.updateConnectedLampDisplay(app.CallingApp.Bus.isConnected());
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refreshSerialPortList();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Bus.clearOnConnectStateChangeCallback();
            app.CallingApp.closingConfigApp();
            delete(app)
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.CallingApp.Bus.connect();
        end

        % Value changed function: SerialportDropDown
        function SerialportDropDownValueChanged(app, event)
            value = app.SerialportDropDown.Value;
            app.CallingApp.Bus.setSerialPort(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SerialportDropDown = uidropdown(app.GridLayout);
            app.SerialportDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialportDropDownValueChanged, true);
            app.SerialportDropDown.Placeholder = 'Pick';
            app.SerialportDropDownLabel = uilabel(app.GridLayout);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.RefreshButton = uibutton(app.GridLayout, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ConnectedLamp = uilamp(app.GridLayout);
            app.ConnectedLampLabel = uilabel(app.GridLayout);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectButton = uibutton(app.GridLayout, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.TODOAlsoaddmotioncontrollerandVNAgpibaddresseshereLabel = uilabel(app.GridLayout);
            app.TODOAlsoaddmotioncontrollerandVNAgpibaddresseshereLabel.Text = 'TODO: Also add motion controller and VNA gpib addresses here.';
            app.LogBox = uitextarea(app.GridLayout);
            app.LogBox.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Config(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end