classdef ManageJobs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        EditJobButton                   matlab.ui.control.Button
        DeleteJobButton                 matlab.ui.control.Button
        NewJobButton                    matlab.ui.control.Button
        JobsListBox                     matlab.ui.control.ListBox
        JobsLabel                       matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        JobDetailsGridLayout            matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        AxisRangeTab                    matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        Axis3On                         matlab.ui.control.Switch
        Axis2On                         matlab.ui.control.Switch
        Axis1On                         matlab.ui.control.Switch
        ActiveLabel                     matlab.ui.control.Label
        Axis2Increment                  matlab.ui.control.NumericEditField
        Axis3Stop                       matlab.ui.control.NumericEditField
        Axis3Increment                  matlab.ui.control.NumericEditField
        Axis2Stop                       matlab.ui.control.NumericEditField
        Axis3Start                      matlab.ui.control.NumericEditField
        Axis2Start                      matlab.ui.control.NumericEditField
        Axis3Label                      matlab.ui.control.Label
        Axis2Label                      matlab.ui.control.Label
        IncrementLabel                  matlab.ui.control.Label
        StopLabel                       matlab.ui.control.Label
        StartLabel                      matlab.ui.control.Label
        Axis1Increment                  matlab.ui.control.NumericEditField
        Axis1Stop                       matlab.ui.control.NumericEditField
        Axis1Start                      matlab.ui.control.NumericEditField
        Axis1Label                      matlab.ui.control.Label
        FrequencyRangeTab               matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        NumFreqPtsEditField             matlab.ui.control.NumericEditField
        FreqsEditFieldLabel             matlab.ui.control.Label
        StopFrequencyHzEditField        matlab.ui.control.NumericEditField
        StopFrequencyHzEditFieldLabel   matlab.ui.control.Label
        StartFrequencyHzEditField       matlab.ui.control.NumericEditField
        StartFrequencyHzEditFieldLabel  matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        JobnameEditFieldLabel           matlab.ui.control.Label
        JobName                         matlab.ui.control.EditField
        SweepModeDropDownLabel          matlab.ui.control.Label
        SweepModeDropDown               matlab.ui.control.DropDown
        GridLayout5                     matlab.ui.container.GridLayout
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CallingApp % Reference back to the main app
    end
    
    methods (Access = private)
        
        function name = getAxisName(app, axis)
            name = app.CallingApp.MotionController.getName(axis);
        end
        
        function enableAxis(app, axis)
            switch axis
                case 1
                    app.Axis1Start.Enable = 1;
                    app.Axis1Stop.Enable = 1;
                    app.Axis1Increment.Enable = 1;
                    app.Axis1On.Value = "On";
                case 2
                    app.Axis2Start.Enable = 1;
                    app.Axis2Stop.Enable = 1;
                    app.Axis2Increment.Enable = 1;
                    app.Axis2On.Value = "On";
                case 3
                    app.Axis3Start.Enable = 1;
                    app.Axis3Stop.Enable = 1;
                    app.Axis3Increment.Enable = 1;
                    app.Axis3On.Value = "On";
                otherwise
                    error('Unknown axis: ' + axis);
            end
        end
        
        function disableAxis(app, axis)
            switch axis
                case 1
                    app.Axis1Start.Enable = 0;
                    app.Axis1Stop.Enable = 0;
                    app.Axis1Increment.Enable = 0;
                    app.Axis1On.Value = "Off";
                case 2
                    app.Axis2Start.Enable = 0;
                    app.Axis2Stop.Enable = 0;
                    app.Axis2Increment.Enable = 0;
                    app.Axis2On.Value = "Off";
                case 3
                    app.Axis3Start.Enable = 0;
                    app.Axis3Stop.Enable = 0;
                    app.Axis3Increment.Enable = 0;
                    app.Axis3On.Value = "Off";
                otherwise
                    error('Unknown axis: ' + axis);
            end
        end
        
        function destroyJob(app, src, event)
            if event.SelectedOption == "OK"
                job = {app.JobsListBox.Value};
                app.CallingApp.destroyJob(job)
                app.refreshJobNamesList();
            else
                % do nothing
            end
        end
        
        function showJobDetails(app)
            app.JobDetailsGridLayout.Visible = 1;
        end
        
        function hideJobDetails(app)
            app.JobDetailsGridLayout.Visible = 0;
        end
        
        function name = determineNewJobName(app)
            % TODO: Check jobs list and pick a new name accordingly
            name = 'New Job';
        end
        
        function updateJobNameInList(app, oldName, newName)
            [~,idx] = ismember(oldName, app.JobsListBox.Items);
            app.JobsListBox.Items(idx) = {newName};
        end
        
        % called when user selects a job from the list
        function loadJob(app, job)
            j = app.CallingApp.getJob(job);

            app.JobName.Value = j.name{1}; % string in cell array
            app.Axis1Start.Value = j.axes(1).start;
            app.Axis1Stop.Value = j.axes(1).stop;
            app.Axis1Increment.Value = j.axes(1).increment;
            app.Axis2Start.Value = j.axes(2).start;
            app.Axis2Stop.Value = j.axes(2).stop;
            app.Axis2Increment.Value = j.axes(2).increment;
            app.Axis3Start.Value = j.axes(3).start;
            app.Axis3Stop.Value = j.axes(3).stop;
            app.Axis3Increment.Value = j.axes(3).increment;

            if j.axes(1).enable == "On"
                app.enableAxis(1);
            else
                app.disableAxis(1);
            end

            if j.axes(2).enable == "On"
                app.enableAxis(2);
            else
                app.disableAxis(2);
            end

            if j.axes(3).enable == "On"
                app.enableAxis(3);
            else
                app.disableAxis(3);
            end

            app.SweepModeDropDown.Value = j.sweepMode;
            app.StartFrequencyHzEditField.Value = j.startFreq;
            app.StopFrequencyHzEditField.Value = j.stopFreq;
            app.NumFreqPtsEditField.Value = j.numPts;

            app.showJobDetails();
        end
        
        function saveJob(app)
            j = struct();
            j.version = 1;
            j.name = {app.JobName.Value};
            j.sweepMode = app.SweepModeDropDown.Value;
            axis1.enable = app.Axis1On.Value;
            axis1.start = app.Axis1Start.Value;
            axis1.stop = app.Axis1Stop.Value;
            axis1.increment = app.Axis1Increment.Value;
            axis2.enable = app.Axis2On.Value;
            axis2.start = app.Axis2Start.Value;
            axis2.stop = app.Axis2Stop.Value;
            axis2.increment = app.Axis2Increment.Value;
            axis3.enable = app.Axis3On.Value;
            axis3.start = app.Axis3Start.Value;
            axis3.stop = app.Axis3Stop.Value;
            axis3.increment = app.Axis3Increment.Value;
            j.axes = [axis1, axis2, axis3];
            j.startFreq = app.StartFrequencyHzEditField.Value;
            j.stopFreq = app.StopFrequencyHzEditField.Value;
            j.numPts = app.NumFreqPtsEditField.Value;

            app.CallingApp.saveJob(j);
        end
        
        function refreshJobNamesList(app)
            names = app.CallingApp.getJobNames();
            app.JobsListBox.Items = names;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;

            % Label axes based on information from controller
            app.Axis1Label.Text = app.getAxisName(1);
            app.Axis2Label.Text = app.getAxisName(2);
            app.Axis3Label.Text = app.getAxisName(3);

            app.hideJobDetails();
            app.refreshJobNamesList();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.closingManageJobs();
            delete(app)
        end

        % Button pushed function: NewJobButton
        function NewJobButtonPushed(app, event)
            jobName = app.determineNewJobName();
            app.JobsListBox.Items(end+1) = {jobName};
            app.JobsListBox.Value = jobName;
            app.showJobDetails();
            app.JobName.Value = jobName;
            app.Axis1Start.Value = 0;
            app.Axis1Stop.Value = 0;
            app.Axis1Increment.Value = 0;
            app.Axis2Start.Value = 0;
            app.Axis2Stop.Value = 0;
            app.Axis2Increment.Value = 0;
            app.Axis3Start.Value = 0;
            app.Axis3Stop.Value = 0;
            app.Axis3Increment.Value = 0;
            app.enableAxis(1);
            app.disableAxis(2);
            app.disableAxis(3);
            % TODO: This doesn't work.
            %uicontrol(app.JobName); % move focus to text box
        end

        % Value changed function: Axis1On
        function Axis1OnValueChanged(app, event)
            value = app.Axis1On.Value;
            if value == "On"
                app.enableAxis(1);
            else
                app.disableAxis(1);
            end
        end

        % Value changed function: Axis2On
        function Axis2OnValueChanged(app, event)
            value = app.Axis2On.Value;
            if value == "On"
                app.enableAxis(2);
            else
                app.disableAxis(2);
            end
        end

        % Value changed function: Axis3On
        function Axis3OnValueChanged(app, event)
            value = app.Axis3On.Value;
            if value == "On"
                app.enableAxis(3);
            else
                app.disableAxis(3);
            end
        end

        % Button pushed function: DeleteJobButton
        function DeleteJobButtonPushed(app, event)
            uiconfirm(app.UIFigure,'Are you sure you want to delete this job?','Delete Job',...
            'CloseFcn',@(src,event)destroyJob(app,src,event));
        end

        % Value changed function: JobsListBox
        function JobsListBoxValueChanged(app, event)
            value = app.JobsListBox.Value;
            % TODO: Decide whether or not Edit button is active?
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.hideJobDetails();
        end

        % Value changed function: JobName
        function JobNameValueChanged(app, event)
             oldName = event.PreviousValue;
             newName = app.JobName.Value;
             % Update the list to have the correct new name
             app.updateJobNameInList(oldName, newName);
             app.JobsListBox.Value = newName;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.saveJob();
            app.hideJobDetails();
        end

        % Button pushed function: EditJobButton
        function EditJobButtonPushed(app, event)
            value = {app.JobsListBox.Value};
            app.loadJob(value);
            app.showJobDetails();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.JobsLabel = uilabel(app.GridLayout2);
            app.JobsLabel.Text = 'Jobs';
            app.JobsListBox = uilistbox(app.GridLayout2);
            app.JobsListBox.ValueChangedFcn = createCallbackFcn(app, @JobsListBoxValueChanged, true);
            app.NewJobButton = uibutton(app.GridLayout2, 'push');
            app.NewJobButton.ButtonPushedFcn = createCallbackFcn(app, @NewJobButtonPushed, true);
            app.NewJobButton.Text = 'New Job';
            app.DeleteJobButton = uibutton(app.GridLayout2, 'push');
            app.DeleteJobButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteJobButtonPushed, true);
            app.DeleteJobButton.Text = 'Delete Job';
            app.EditJobButton = uibutton(app.GridLayout2, 'push');
            app.EditJobButton.ButtonPushedFcn = createCallbackFcn(app, @EditJobButtonPushed, true);
            app.EditJobButton.Text = 'Edit Job';
            app.RightPanel = uipanel(app.GridLayout);
            app.JobDetailsGridLayout = uigridlayout(app.RightPanel);
            app.GridLayout5 = uigridlayout(app.JobDetailsGridLayout);
            app.SaveButton = uibutton(app.GridLayout5, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout5, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.GridLayout6 = uigridlayout(app.JobDetailsGridLayout);
            app.SweepModeDropDown = uidropdown(app.GridLayout6);
            app.SweepModeDropDown.Items = {'Bidirectional (Lawnmower)', 'Unidirectional (Typewriter)'};
            app.SweepModeDropDown.ItemsData = {'bidirectional', 'unidirectional'};
            app.SweepModeDropDown.Value = 'bidirectional';
            app.SweepModeDropDownLabel = uilabel(app.GridLayout6);
            app.SweepModeDropDownLabel.Text = 'Sweep Mode';
            app.JobName = uieditfield(app.GridLayout6, 'text');
            app.JobName.ValueChangedFcn = createCallbackFcn(app, @JobNameValueChanged, true);
            app.JobnameEditFieldLabel = uilabel(app.GridLayout6);
            app.JobnameEditFieldLabel.Text = 'Job name';
            app.TabGroup = uitabgroup(app.JobDetailsGridLayout);
            app.AxisRangeTab = uitab(app.TabGroup);
            app.AxisRangeTab.Title = 'Axis Range';
            app.GridLayout4 = uigridlayout(app.AxisRangeTab);
            app.Axis1Label = uilabel(app.GridLayout4);
            app.Axis1Label.Text = 'Axis 1';
            app.Axis1Start = uieditfield(app.GridLayout4, 'numeric');
            app.Axis1Stop = uieditfield(app.GridLayout4, 'numeric');
            app.Axis1Increment = uieditfield(app.GridLayout4, 'numeric');
            app.StartLabel = uilabel(app.GridLayout4);
            app.StartLabel.Text = 'Start';
            app.StopLabel = uilabel(app.GridLayout4);
            app.StopLabel.Text = 'Stop';
            app.IncrementLabel = uilabel(app.GridLayout4);
            app.IncrementLabel.Text = 'Increment';
            app.Axis2Label = uilabel(app.GridLayout4);
            app.Axis2Label.Text = 'Axis 2';
            app.Axis3Label = uilabel(app.GridLayout4);
            app.Axis3Label.Text = 'Axis 3';
            app.Axis2Start = uieditfield(app.GridLayout4, 'numeric');
            app.Axis3Start = uieditfield(app.GridLayout4, 'numeric');
            app.Axis2Stop = uieditfield(app.GridLayout4, 'numeric');
            app.Axis3Increment = uieditfield(app.GridLayout4, 'numeric');
            app.Axis3Stop = uieditfield(app.GridLayout4, 'numeric');
            app.Axis2Increment = uieditfield(app.GridLayout4, 'numeric');
            app.ActiveLabel = uilabel(app.GridLayout4);
            app.ActiveLabel.Text = 'Active';
            app.Axis1On = uiswitch(app.GridLayout4, 'slider');
            app.Axis1On.ValueChangedFcn = createCallbackFcn(app, @Axis1OnValueChanged, true);
            app.Axis1On.Value = 'On';
            app.Axis2On = uiswitch(app.GridLayout4, 'slider');
            app.Axis2On.ValueChangedFcn = createCallbackFcn(app, @Axis2OnValueChanged, true);
            app.Axis2On.Value = 'On';
            app.Axis3On = uiswitch(app.GridLayout4, 'slider');
            app.Axis3On.ValueChangedFcn = createCallbackFcn(app, @Axis3OnValueChanged, true);
            app.Axis3On.Value = 'On';
            app.FrequencyRangeTab = uitab(app.TabGroup);
            app.FrequencyRangeTab.Title = 'Frequency Range';
            app.GridLayout7 = uigridlayout(app.FrequencyRangeTab);
            app.StartFrequencyHzEditFieldLabel = uilabel(app.GridLayout7);
            app.StartFrequencyHzEditFieldLabel.Text = 'Start Frequency (Hz)';
            app.StartFrequencyHzEditField = uieditfield(app.GridLayout7, 'numeric');
            app.StartFrequencyHzEditField.Value = 1000000000;
            app.StopFrequencyHzEditFieldLabel = uilabel(app.GridLayout7);
            app.StopFrequencyHzEditFieldLabel.Text = 'Stop Frequency (Hz)';
            app.StopFrequencyHzEditField = uieditfield(app.GridLayout7, 'numeric');
            app.StopFrequencyHzEditField.Value = 3000000000;
            app.FreqsEditFieldLabel = uilabel(app.GridLayout7);
            app.FreqsEditFieldLabel.Text = '# Freqs';
            app.NumFreqPtsEditField = uieditfield(app.GridLayout7, 'numeric');
            app.NumFreqPtsEditField.Value = 201;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManageJobs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end