classdef ManageProjects < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManageProjectsUIFigure  matlab.ui.Figure
        ConfirmationPanel       matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        NoButton                matlab.ui.control.Button
        YesButton               matlab.ui.control.Button
        DeletionLabel           matlab.ui.control.Label
        MainPanel               matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        RenameButton            matlab.ui.control.StateButton
        DeleteButton            matlab.ui.control.Button
        ListBox                 matlab.ui.control.ListBox
        CreateButton            matlab.ui.control.Button
        QuitButton              matlab.ui.control.Button
        ChooseButton            matlab.ui.control.Button
        EditField               matlab.ui.control.EditField
        NewProjectLabel         matlab.ui.control.Label
        AllProjectsLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp  %reference back to main app..or something like that
    end
    
    properties (Access = public)    
        NPath   %new path
    end
    
    methods (Access = private)
        
        function updateList(app) %sets all current project names
            files = dir(app.NPath);
            dirFlags = [files.isdir];
            subFolders = files(dirFlags);
            subFolderNames = {subFolders(3:end).name};
            app.ListBox.Items = subFolderNames;
        end
           
        function deleteProject(app)
            proj = fullfile(app.NPath,app.ListBox.Value);
            rmdir(proj,'s') %remove project and any jobs
            updateList(app);
            set(app.ListBox,'Value',{});
            setbuttons(app, "off");
        end
        
        function setbuttons(app,state)
            app.ChooseButton.Enable = state;
            app.RenameButton.Enable = state;
            app.DeleteButton.Enable = state;
        end
        
        function createProject(app,newproj)
            if isfolder(newproj) == 1
                msgbox("Project already exists.")
            else
                mkdir(newproj)
                app.EditField.Value = ''; %clears entry
                updateList(app);
            end
        end
    end
    
    methods (Access = public)
        
        function setProject(app,name) %return project name and path to main app
            path = fullfile(app.NPath, name); %generate path from name
            updateProject(app.CallingApp,name,path) %Function in main app
            app.delete
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp; %store main app object

            %Generate Projects folder in GITHUB directory
            %dpath = cd; %get directory
            % if ~isfolder('Projects')
            %     mkdir('Projects');    %create master project folder
            % end
            % app.NPath = fullfile(dpath,'Projects');


            %Generate Projects folder outside of GITHUB directory
            userProfile = getenv("USERPROFILE");
            Projfolder = sprintf("%s\\Documents\\Projects", userProfile);
            if ~isfolder(Projfolder)
                mkdir(Projfolder);    %create master project folder
            end
            app.NPath = Projfolder;

            updateList(app);
            set(app.ListBox,'Value',{})   % deselects current value

            app.CreateButton.Enable = "off";
            setbuttons(app,"off")
            app.ConfirmationPanel.Visible = "off";
            app.RenameButton.Interruptible = "off";
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            if app.RenameButton.Interruptible == "on"
                app.CreateButton.Enable = "off";
            else
                app.CreateButton.Enable = "on";
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            newproj = fullfile(app.NPath,app.EditField.Value);
            createProject(app,newproj);
            app.CreateButton.Enable = "off";
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            app.delete
        end

        % Double-clicked callback: ListBox
        function ListBoxDoubleClicked(app, event)
            if ~isempty(app.ListBox.Value) == 1
                setProject(app,app.ListBox.Value);
            end
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            setProject(app,app.ListBox.Value);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            path = fullfile(app.NPath,app.ListBox.Value);
            file = fullfile(path,"jobs.mat");
            if isfile(file)
                app.MainPanel.Visible = "off";
                app.ConfirmationPanel.Position = get(app.MainPanel, 'Position');

                dims = load(file,"jobs");
                [~,numjobs] = size(dims.jobs);
                app.DeletionLabel.Text = "This project contains " + numjobs + " job(s). Are you sure you want to delete it?";
                app.ConfirmationPanel.Visible = "on";
            else
                deleteProject(app)
            end
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            item = event.InteractionInformation.Item; %enable buttons if list item is selected
            if isempty(item) == 0
                setbuttons(app,"on")
            end
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.ConfirmationPanel.Visible = "off";
            app.MainPanel.Visible = "on";
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            deleteProject(app);
            app.ConfirmationPanel.Visible = "off";
            app.MainPanel.Visible = "on";
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            if app.RenameButton.Interruptible == 0
                entry = event.Value;
                if isempty(entry) == 1
                    app.CreateButton.Enable = "off";
                else
                    app.CreateButton.Enable = "on";
                end
            end
        end

        % Value changed function: RenameButton
        function RenameButtonValueChanged(app, event)
            state = app.RenameButton.Value; % logical 1 = button pressed
            if state == 1
                app.RenameButton.Interruptible = "on";
                app.RenameButton.Text = "Save";
                app.ChooseButton.Enable = "off";
                app.DeleteButton.Enable = "off";
                app.EditField.Value = app.ListBox.Value;
            else
                if strcmp(string(app.EditField.Value),string(app.ListBox.Value)) == 0
                    source = fullfile(app.NPath,app.ListBox.Value);
                    dest = fullfile(app.NPath,app.EditField.Value);
                    movefile(source,dest);
                    updateList(app);
                end
                app.EditField.Value = "";
                app.RenameButton.Text = "Rename";
                app.ChooseButton.Enable = "on";
                app.DeleteButton.Enable = "on";
                app.RenameButton.Interruptible = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManageProjectsUIFigure = uifigure('Visible', 'off');
            app.ManageProjectsUIFigure.Name = 'Manage Projects';
            app.MainPanel = uipanel(app.ManageProjectsUIFigure);
            app.GridLayout = uigridlayout(app.MainPanel);
            app.AllProjectsLabel = uilabel(app.GridLayout);
            app.AllProjectsLabel.Text = 'All Projects';
            app.NewProjectLabel = uilabel(app.GridLayout);
            app.NewProjectLabel.Text = 'New Project';
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.EditField.Placeholder = 'Enter Project Name';
            app.ChooseButton = uibutton(app.GridLayout, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.QuitButton = uibutton(app.GridLayout, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.CreateButton = uibutton(app.GridLayout, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.DoubleClickedFcn = createCallbackFcn(app, @ListBoxDoubleClicked, true);
            app.DeleteButton = uibutton(app.GridLayout, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.RenameButton = uibutton(app.GridLayout, 'state');
            app.RenameButton.ValueChangedFcn = createCallbackFcn(app, @RenameButtonValueChanged, true);
            app.RenameButton.Text = 'Rename';
            app.ConfirmationPanel = uipanel(app.ManageProjectsUIFigure);
            app.GridLayout2 = uigridlayout(app.ConfirmationPanel);
            app.DeletionLabel = uilabel(app.GridLayout2);
            app.YesButton = uibutton(app.GridLayout2, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.GridLayout2, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            % Show the figure after all components are created
            app.ManageProjectsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManageProjects(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManageProjectsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManageProjectsUIFigure)
        end
    end
end