classdef MotionControllerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LogBox                        matlab.ui.control.TextArea
        TabGroup                      matlab.ui.container.TabGroup
        ManualControlTab              matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        STOPALLButton                 matlab.ui.control.Button
        Axis4Panel                    matlab.ui.container.Panel
        Axis2Panel                    matlab.ui.container.Panel
        Axis1Panel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        Axis1FaultLamp                matlab.ui.control.Lamp
        FaultLampLabel                matlab.ui.control.Label
        Axis1MovingLamp               matlab.ui.control.Lamp
        MovingLampLabel               matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        PosDecrementBtn               matlab.ui.control.Button
        PosIncrementBtn               matlab.ui.control.Button
        MovetoButton                  matlab.ui.control.Button
        TargetPositionEditField       matlab.ui.control.NumericEditField
        TargetPositionEditFieldLabel  matlab.ui.control.Label
        Axis1PositionLabel            matlab.ui.control.Label
        ConfigTab                     matlab.ui.container.Tab
        GetErrorsButton               matlab.ui.control.Button
        GetStatusButton               matlab.ui.control.Button
        ConnectButton                 matlab.ui.control.Button
        ConnectedLamp                 matlab.ui.control.Lamp
        ConnectedLampLabel            matlab.ui.control.Label
        GPIBAddressDropDown           matlab.ui.control.DropDown
        GPIBAddressDropDownLabel      matlab.ui.control.Label
        SavelogButton                 matlab.ui.control.Button
        ClearlogButton                matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
        SerialportDropDown            matlab.ui.control.DropDown
        SerialportDropDownLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Reference back to the main app
    end
    
    methods (Access = private)
        function refreshSerialPortList(app)
            app.SerialportDropDown.Items = seriallist();
        end

        function name = getAxisName(app, axis)
            name = app.CallingApp.MotionController.getName(axis);
        end

        function updateAxisPositionDisplay(app, axis, position)
            if position >= 0
                pos = sprintf("+ %.03f", position);
            else
                pos = sprintf("- %.03f", -1*position);
            end

            switch axis
                case 1
                    app.Axis1PositionLabel.Text = pos;
                case 2
                    % app.Axis2PositionLabel.Text = pos;
                case 3
                    % app.Axis3PositionLabel.Text = pos;
                otherwise
                    error('Unknown axis: ' + axis);
            end
        end
        
        function onAxisStateChange(app, state)
            % This is the onStateChange callback for MotionController.

            if state.axis == 1
                if state.moving
                    app.Axis1MovingLamp.Color = 'green';
                else
                    app.Axis1MovingLamp.Color = [0.80, 0.80, 0.80];
                end

                if state.fault
                    app.Axis1FaultLamp.Color = 'red';
                else
                    app.Axis1FaultLamp.Color = [0.80, 0.80, 0.80];
                end

                app.updateAxisPositionDisplay(state.axis, state.position);
            end
        end

        function updateConnectedLampDisplay(app, state)
            % true means connected, false means disconnected
            if state
                app.ConnectedLamp.Color = 'green';
                app.ConnectButton.Enable = false;
                app.GetStatusButton.Enable = true;
                app.GetErrorsButton.Enable = true;
            else
                app.ConnectedLamp.Color = 'red';
                app.ConnectButton.Enable = true;
                app.GetStatusButton.Enable = false;
                app.GetErrorsButton.Enable = false;
            end
        end

        function onConnectStateChange(app, state)
            % TODO: Probably just move updateConnectedLampDisplay() into
            % here.
            app.updateConnectedLampDisplay(state);
        end
    end
    
    methods (Access = public)
        
        function onLogEvent(app, msg, logs)
            if msg == ""
                app.LogBox.Value = logs;
            else
                % Add an extra newline due to a bug in scroll().
                % I left a comment about it here:
                % https://www.mathworks.com/matlabcentral/answers/351131-how-do-i-make-a-full-text-area-scroll-up-when-i-add-more-text
                app.LogBox.Value = strcat(logs, {newline});

                % I tried only appending the new data,
                % but nothing seems to make matlab happy.
                % https://www.mathworks.com/matlabcentral/answers/747777-why-n-or-newline-doesn-t-work?s_tid=answers_rc1-2_p2_MLT
                %app.LogBox.Value = strcat(app.LogBox.Value, {newline}, "asdf");
                %app.LogBox.Value = strcat(app.LogBox.Value, msg, {newline});
            end

            scroll(app.LogBox, 'bottom');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.LogBox.Value = app.CallingApp.MotionControlLogger.GetLog();

            app.refreshSerialPortList();

            app.CallingApp.MotionController.setOnStateChangeCallback(@(s) app.onAxisStateChange(s));
            app.CallingApp.MotionController.setOnConnectStateChangeCallback(@(s) app.onConnectStateChange(s));

            % Get connection state
            app.updateConnectedLampDisplay(app.CallingApp.MotionController.isConnected());

            % TODO: get serial port from app config

            % Get GPIB address
            addr = app.CallingApp.MotionController.getGPIBAddress();
            app.GPIBAddressDropDown.Value = ""+addr;

            % Label axes based on information from controller
            app.Axis1Panel.Title = app.getAxisName(1);
            app.Axis2Panel.Title = app.getAxisName(2);
            app.Axis4Panel.Title = app.getAxisName(4);

            % Get current position data
            pos = app.CallingApp.MotionController.getPosition(1);
            app.updateAxisPositionDisplay(1, pos);
            % TODO: other axes
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.MotionController.clearOnStateChangeCallback();
            app.CallingApp.MotionController.clearOnConnectStateChangeCallback();
            app.CallingApp.closingMotionControlApp();
            delete(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refreshSerialPortList();
        end

        % Value changed function: SerialportDropDown
        function SerialportDropDownValueChanged(app, event)
            value = app.SerialportDropDown.Value;
            app.CallingApp.MotionController.setSerialPort(value);
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
            app.CallingApp.MotionControlLogger.Clear();
        end

        % Button pushed function: SavelogButton
        function SavelogButtonPushed(app, event)
            msgbox('TODO');
            % use app.CallingApp.Logger.GetLog()
        end

        % Button pushed function: STOPALLButton
        function STOPALLButtonPushed(app, event)
            app.CallingApp.MotionController.stopAll();
        end

        % Button pushed function: MovetoButton
        function MovetoButtonPushed(app, event)
            pos = app.TargetPositionEditField.Value;
            app.CallingApp.MotionController.moveTo(1, pos);
        end

        % Button pushed function: PosIncrementBtn
        function PosIncrementBtnPushed(app, event)
            app.CallingApp.MotionController.moveIncremental(1, 5);
        end

        % Button pushed function: PosDecrementBtn
        function PosDecrementBtnButtonPushed(app, event)
            app.CallingApp.MotionController.moveIncremental(1, -5);
        end

        % Value changed function: GPIBAddressDropDown
        function GPIBAddressDropDownValueChanged(app, event)
            addr = uint8(str2double(app.GPIBAddressDropDown.Value));
            app.CallingApp.MotionController.setGPIBAddress(addr);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.CallingApp.MotionController.connect();
        end

        % Callback function
        function ButtonPushed(app, event)
            app.LogBox.Value = strcat(app.LogBox.Value, {newline}, "asdf");
        end

        % Button pushed function: GetStatusButton
        function GetStatusButtonPushed(app, event)
            % TODO: Right now this is just for axis 1
            % results are dumped into the log.
            app.CallingApp.MotionController.getStatus(1);
        end

        % Button pushed function: GetErrorsButton
        function GetErrorsButtonPushed(app, event)
            % results are dumped into the log.
            app.CallingApp.MotionController.getErrors();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ManualControlTab = uitab(app.TabGroup);
            app.ManualControlTab.Title = 'Manual Control';
            app.GridLayout2 = uigridlayout(app.ManualControlTab);
            app.Axis1Panel = uipanel(app.GridLayout2);
            app.Axis1Panel.Title = 'Axis 1';
            app.GridLayout3 = uigridlayout(app.Axis1Panel);
            app.Axis1PositionLabel = uilabel(app.GridLayout3);
            app.Axis1PositionLabel.Text = '+ 0.00';
            app.Panel = uipanel(app.GridLayout3);
            app.TargetPositionEditFieldLabel = uilabel(app.Panel);
            app.TargetPositionEditFieldLabel.Text = 'Target Position';
            app.TargetPositionEditField = uieditfield(app.Panel, 'numeric');
            app.MovetoButton = uibutton(app.Panel, 'push');
            app.MovetoButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoButtonPushed, true);
            app.MovetoButton.Text = 'Move to';
            app.PosIncrementBtn = uibutton(app.Panel, 'push');
            app.PosIncrementBtn.ButtonPushedFcn = createCallbackFcn(app, @PosIncrementBtnPushed, true);
            app.PosIncrementBtn.Text = '+ 5';
            app.PosDecrementBtn = uibutton(app.Panel, 'push');
            app.PosDecrementBtn.ButtonPushedFcn = createCallbackFcn(app, @PosDecrementBtnButtonPushed, true);
            app.PosDecrementBtn.Text = '- 5';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.MovingLampLabel = uilabel(app.GridLayout4);
            app.MovingLampLabel.Text = 'Moving';
            app.Axis1MovingLamp = uilamp(app.GridLayout4);
            app.FaultLampLabel = uilabel(app.GridLayout4);
            app.FaultLampLabel.Text = 'Fault';
            app.Axis1FaultLamp = uilamp(app.GridLayout4);
            app.Axis2Panel = uipanel(app.GridLayout2);
            app.Axis2Panel.Title = 'Axis 2';
            app.Axis4Panel = uipanel(app.GridLayout2);
            app.Axis4Panel.Title = 'Axis 4';
            app.STOPALLButton = uibutton(app.GridLayout2, 'push');
            app.STOPALLButton.ButtonPushedFcn = createCallbackFcn(app, @STOPALLButtonPushed, true);
            app.STOPALLButton.Text = 'STOP ALL';
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.SerialportDropDownLabel = uilabel(app.ConfigTab);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.ConfigTab);
            app.SerialportDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialportDropDownValueChanged, true);
            app.SerialportDropDown.Placeholder = 'Pick';
            app.RefreshButton = uibutton(app.ConfigTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ClearlogButton = uibutton(app.ConfigTab, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
            app.SavelogButton = uibutton(app.ConfigTab, 'push');
            app.SavelogButton.ButtonPushedFcn = createCallbackFcn(app, @SavelogButtonPushed, true);
            app.SavelogButton.Text = 'Save log...';
            app.GPIBAddressDropDownLabel = uilabel(app.ConfigTab);
            app.GPIBAddressDropDownLabel.Text = 'GPIB Address';
            app.GPIBAddressDropDown = uidropdown(app.ConfigTab);
            app.GPIBAddressDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.GPIBAddressDropDown.ValueChangedFcn = createCallbackFcn(app, @GPIBAddressDropDownValueChanged, true);
            app.GPIBAddressDropDown.Placeholder = 'Pick';
            app.GPIBAddressDropDown.Value = '0';
            app.ConnectedLampLabel = uilabel(app.ConfigTab);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.ConfigTab);
            app.ConnectButton = uibutton(app.ConfigTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.GetStatusButton = uibutton(app.ConfigTab, 'push');
            app.GetStatusButton.ButtonPushedFcn = createCallbackFcn(app, @GetStatusButtonPushed, true);
            app.GetStatusButton.Text = 'Get Status';
            app.GetErrorsButton = uibutton(app.ConfigTab, 'push');
            app.GetErrorsButton.ButtonPushedFcn = createCallbackFcn(app, @GetErrorsButtonPushed, true);
            app.GetErrorsButton.Text = 'Get Errors';
            app.LogBox = uitextarea(app.GridLayout);
            app.LogBox.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionControllerApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end