classdef RunJobs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        OverviewTab                 matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        StatusLamp                  matlab.ui.control.Lamp
        StatusLampLabel             matlab.ui.control.Label
        Axis3Label                  matlab.ui.control.Label
        Axis2Label                  matlab.ui.control.Label
        Axis1Label                  matlab.ui.control.Label
        ProgressLabel               matlab.ui.control.Label
        Axis3Gauge                  matlab.ui.control.LinearGauge
        Axis2Gauge                  matlab.ui.control.LinearGauge
        Axis1Gauge                  matlab.ui.control.LinearGauge
        ProgressGauge               matlab.ui.control.LinearGauge
        MeasurementsTab             matlab.ui.container.Tab
        GridLayout5                 matlab.ui.container.GridLayout
        GridLayout6                 matlab.ui.container.GridLayout
        NormalizeButton             matlab.ui.control.StateButton
        EstTimeRemainingLabel       matlab.ui.control.Label
        WorkingLabel                matlab.ui.control.Label
        SlicePositionDropDown       matlab.ui.control.DropDown
        SlicePositionDropDownLabel  matlab.ui.control.Label
        SliceAxisDropDown           matlab.ui.control.DropDown
        SliceAxisDropDownLabel      matlab.ui.control.Label
        FrequencyDropDown           matlab.ui.control.DropDown
        FrequencyDropDownLabel      matlab.ui.control.Label
        MeasurementsAxes            matlab.ui.control.UIAxes
        LogsTab                     matlab.ui.container.Tab
        GridLayout7                 matlab.ui.container.GridLayout
        LogBox                      matlab.ui.control.TextArea
        GridLayout2                 matlab.ui.container.GridLayout
        QueueJobButton              matlab.ui.control.Button
        PrepareJobNowButton         matlab.ui.control.Button
        JobsListBox                 matlab.ui.control.ListBox
        JobsLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Reference back to the main app
        plan       % The measurement plan to run. Populated when job is selected.
        jobRunner  % JobRunner object instance
    end
    
    methods (Access = private)
        function name = getAxisName(app, axis)
            name = app.CallingApp.MotionController.getName(axis);
        end

        function pos = getAxisPosition(app, axis)
            pos = app.CallingApp.MotionController.getPosition(axis);
        end

        function limits = getAxisLimits(app, axis)
            low = app.CallingApp.MotionController.getReverseSoftLimit(axis);
            high = app.CallingApp.MotionController.getForwardSoftLimit(axis);
            limits = [low, high];
        end

        function refreshJobNamesList(app)
            names = app.CallingApp.getJobNames();
            app.JobsListBox.Items = names;
        end

        function updateAxisPositionDisplay(app, axis, position)
            switch axis
                case 1
                    app.Axis1Gauge.Value = position;
                case 2
                    app.Axis2Gauge.Value = position;
                case 3
                    app.Axis3Gauge.Value = position;
                otherwise
                    error("Unknown axis: " + axis);
            end
        end

        function ec = setupStateChangeEventHandler(app)
            ec = app.CallingApp.MotionController.getEventContainer();
            addlistener(ec, "StateChange", @(src, data) app.changeEventHandler(src, data));
        end

        function changeEventHandler(app, eventSrc, state)
            try
                 app.updateAxisPositionDisplay(state.Axis, state.Position);
            catch e
                % This seems to always show up, but if I remove the whole
                % try/catch thing then there's no exception being thrown.
                % So I have no idea what matlab is doing... but it's doing
                % it wrong...
                fprintf("UI error trying to respond to MotionController event in RunJobs.mlapp");
                disp(e);
            end
        end

        function jobChangeEventHandler(app, eventSrc, state)
            try
                if (state.Fault)
                    stateColor = "red";
                elseif (state.Running)
                    stateColor = "green";
                else
                    stateColor = [0.80, 0.80, 0.80];
                end

                app.StatusLamp.Color = stateColor;
                app.ProgressGauge.Value = state.PercentComplete;
            catch e
                fprintf("UI error trying to respond to JobRunner event in RunJobs.mlapp");
                disp(e);
            end
        end

        function measurementEventHandler(app, eventSrc, state)
            try
                % Update time-remaining readout
                if state.TimeRemainingSecs ~= -1
                    % (-1 means we don't have a good estimate yet)
                    s = seconds(state.TimeRemainingSecs);
                    s.Format = "hh:mm:ss";
                    app.EstTimeRemainingLabel.Text = sprintf("Est. remain: %s", s);
                end

                % Update the live measurements plot
                plot(app.MeasurementsAxes, state.Positions, 20*log10(abs(state.S21)));
                %%%plotMeasurement(app, src, data)

                % Update busy indicator
                app.stopWorking();
            catch e
                fprintf("UI error trying to respond to JobController event in RunJobs.mlapp");
                disp(e);                
            end
        end

        % % % function plotMeasurement(app, eventSrc, state)
        % % %     buttonState = app.NormalizeButton.Value;
        % % %     maxValue = max(20*log10(abs(state.S21)));
        % % %     if buttonState == 0
        % % %         ylabel(app.MeasurementsAxes,'Magnitude (dB)','FontWeight','bold')
        % % %         plot(app.MeasurementsAxes, state.Positions, 20*log10(abs(state.S21))); %plots magnitude in dB
        % % %     else
        % % %         ylabel(app.MeasurementsAxes,'Normalized Gain(dB)','FontWeight','bold')
        % % %         plot(app.MeasurementsAxes, state.Positions, 20*log10(abs(state.S21)) - maxValue); %normalizes then plots
        % % %     end


        % % % end

        function onJobStart(app)
            app.StartButton.Enable = "off";
            app.StopButton.Enable = "on";
            app.PrepareJobNowButton.Enable = "off";
            app.JobsListBox.Enable = "off";
            app.EstTimeRemainingLabel.Text = "Calculating...";
            app.EstTimeRemainingLabel.Visible = "on";
            drawnow;
        end

        function onJobStop(app)
            app.StartButton.Enable = "on";
            app.StopButton.Enable = "off";
            app.PrepareJobNowButton.Enable = "on";
            app.JobsListBox.Enable = "on";
            app.EstTimeRemainingLabel.Text = "Complete.";
            drawnow;
        end

        function prepLiveView(app)
            % Frequency dropdown
            freqs = app.jobRunner.getLiveViewFrequenciesAvailable();
            app.FrequencyDropDown.ItemsData = freqs;
            app.FrequencyDropDown.Items = cellstr(num2str(freqs'));
            app.FrequencyDropDown.Value = app.jobRunner.getLiveViewFrequency();

            % Axis dropdown
            axisNames = {};
            for x=1:length(app.plan.axes)
                axisNames(x) = cellstr(app.getAxisName(app.plan.axes(x)));
            end
            app.SliceAxisDropDown.ItemsData = app.plan.axes;
            app.SliceAxisDropDown.Items = cellstr(axisNames);
            app.SliceAxisDropDown.Value = app.jobRunner.getLiveViewAxis();

            if length(app.plan.axes) == 1
                % Axis dropdown
                app.SliceAxisDropDown.Enable = "off";

                % Slice dropdown
                app.SlicePositionDropDown.Enable = "off";
                app.SlicePositionDropDown.Items = cellstr(["n/a"]);
            else
                % Axis dropdown
                app.SliceAxisDropDown.Enable = "on";

                % Slice dropdown
                slices = app.jobRunner.getLiveViewSlicesAvailable();
                app.SlicePositionDropDown.ItemsData = slices;
                app.SlicePositionDropDown.Items = cellstr(num2str(slices));
                app.SlicePositionDropDown.Value = app.jobRunner.getLiveViewSlicePosition();
                app.SlicePositionDropDown.Enable = "on";
            end

            % Plot view
            app.MeasurementsAxes.XGrid = true;
            app.MeasurementsAxes.YGrid = true;
            app.MeasurementsAxes.XLim = app.getLiveViewXLims();
            app.MeasurementsAxes.YLim = [-100 0];

            drawnow;
        end

        function lims = getLiveViewXLims(app)
            % Get the extents for the selected axis
            axis = app.SliceAxisDropDown.Value;
            axisIdx = find(app.plan.axes == axis);

            % Hack hack: We want to show axes for the axis _other than_ the
            % one the user selected. This works for now with 2 axes, but
            % will need serious rework to support 3 axes.
            if length(app.plan.axes) == 2
                if axisIdx == 1
                    axisIdx = 2;
                else
                    axisIdx = 1;
                end
            elseif length(app.plan.axes) > 2
                error("Not supported yet")
            end

            steps = app.plan.steps(:, axisIdx);
            lims = [min(steps), max(steps)];
        end

        function startWorking(app)
            app.WorkingLabel.Visible = "on";
            drawnow;
        end

        function stopWorking(app)
            app.WorkingLabel.Visible = "off";
            drawnow;
        end

        function results = runPlan(app)
            app.onJobStart();

            % We provide the axis names so they can be stored with results
            allAxisNames(1) = {app.getAxisName(1)};
            allAxisNames(2) = {app.getAxisName(2)};
            allAxisNames(3) = {app.getAxisName(3)};

            jobLog = Logger();
            jobLog.SetCallback(@(m, l) app.onLogEvent(m, l));
            %jobLog.echoToCli(true);

            % Create a jobRunner container to handle running the job
            app.jobRunner = JobRunner(allAxisNames, app.CallingApp.MotionController, app.CallingApp.VNA, jobLog);

            % Setup event handlers
            addlistener(app.jobRunner, "StateChange", @(src, data) app.jobChangeEventHandler(src, data));
            addlistener(app.jobRunner, "MeasurementsChange", @(src, data) app.measurementEventHandler(src, data));

            try
                % Prepare motion controller and VNA
                % and prep data for live view
                app.jobRunner.prepJob(app.plan);

                % Prepare axes on live measurements view
                app.prepLiveView();

                % Run the job
                results = app.jobRunner.runJob();

                % Save the results
                if (app.jobRunner.finishedSuccessfully())
                    jobName = app.Panel.Title; % TODO: This is slightly sloppy; not very reliable.
                    dt = datestr(now, "yyyymmdd-HHMM");
                    filename = jobName + " results " + dt + ".mat";
                    % Store the results in the appropriate folder
                    workFolder = app.CallingApp.getWorkingFolder();
                    fullpath = workFolder + "\\" + filename;
                    app.jobRunner.saveResults(fullpath);
                end
            catch (e)
                warning("Job run failed");
                disp(e);
            end

            app.onJobStop();
        end

        function onLogEvent(app, msg, logs)
            try
                if msg == ""
                    app.LogBox.Value = logs;
                else
                    % Add an extra newline due to a bug in scroll().
                    % I left a comment about it here:
                    % https://www.mathworks.com/matlabcentral/answers/351131-how-do-i-make-a-full-text-area-scroll-up-when-i-add-more-text
                    app.LogBox.Value = strcat(logs, {newline});

                    % I tried only appending the new data,
                    % but nothing seems to make matlab happy.
                    % https://www.mathworks.com/matlabcentral/answers/747777-why-n-or-newline-doesn-t-work?s_tid=answers_rc1-2_p2_MLT
                    %app.LogBox.Value = strcat(app.LogBox.Value, {newline}, "asdf");
                    %app.LogBox.Value = strcat(app.LogBox.Value, msg, {newline});
                end

                scroll(app.LogBox, 'bottom');
            catch e
                warning("Error in RunJobs::onLogEvent");
                disp(e);
            end
        end
    end
    
    methods (Access = public)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;

            app.Panel.Visible = false;
            app.refreshJobNamesList();
            app.NormalizeButton.Enable = "off";

            % Label axes based on information from controller
            app.Axis1Label.Text = app.getAxisName(1);
            app.Axis2Label.Text = app.getAxisName(2);
            app.Axis3Label.Text = app.getAxisName(3);

% TODO: The soft-limits don't seem to be useful here. Determine what the
% actual axes limits are; and hope we can find them through the motion
% controller.
            % Set apppropriate limits on the readouts
%             app.Axis1Gauge.Limits = app.getAxisLimits(1);
%             app.Axis2Gauge.Limits = app.getAxisLimits(2);
%             app.Axis3Gauge.Limits = app.getAxisLimits(3);

            % Show current axis positions
            app.updateAxisPositionDisplay(1, app.getAxisPosition(1));
            app.updateAxisPositionDisplay(2, app.getAxisPosition(2));
            app.updateAxisPositionDisplay(3, app.getAxisPosition(3));

            % Set up event handlers
            app.setupStateChangeEventHandler();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.closingRunJobs();
            delete(app)
        end

        % Button pushed function: PrepareJobNowButton
        function PrepareJobNowButtonPushed(app, event)
            app.Panel.Visible = true;

            jobName = {app.JobsListBox.Value};
            j = app.CallingApp.getJob(jobName);

            app.Panel.Title = j.name{1};
            app.plan = planJobRun(j);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StatusLamp.Color = "yellow";
            drawnow;
            app.runPlan();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.jobRunner.stop();
        end

        % Value changed function: FrequencyDropDown
        function FrequencyDropDownValueChanged(app, event)
            app.startWorking();
            freq = app.FrequencyDropDown.Value;
            app.jobRunner.setLiveViewFrequency(freq);
        end

        % Value changed function: SliceAxisDropDown
        function SliceAxisDropDownValueChanged(app, event)
            app.startWorking();
            axis = app.SliceAxisDropDown.Value;
            app.jobRunner.setLiveViewAxis(axis);
            app.prepLiveView();
        end

        % Value changed function: SlicePositionDropDown
        function SlicePositionDropDownValueChanged(app, event)
            app.startWorking();
            slice = app.SlicePositionDropDown.Value;
            app.jobRunner.setLiveViewSlicePosition(slice);
        end

        % Value changed function: NormalizeButton
        function NormalizeButtonValueChanged(app, event)
            % TODO
            % Normalize S21 data and replot after it finishes..or during
            % job run, whichever is more convenient
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.JobsLabel = uilabel(app.GridLayout2);
            app.JobsLabel.Text = 'Jobs';
            app.JobsListBox = uilistbox(app.GridLayout2);
            app.PrepareJobNowButton = uibutton(app.GridLayout2, 'push');
            app.PrepareJobNowButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareJobNowButtonPushed, true);
            app.PrepareJobNowButton.Text = 'Prepare Job Now';
            app.QueueJobButton = uibutton(app.GridLayout2, 'push');
            app.QueueJobButton.Enable = 'off';
            app.QueueJobButton.Text = 'Queue Job';
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.GridLayout4 = uigridlayout(app.Panel);
            app.TabGroup = uitabgroup(app.GridLayout4);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.GridLayout3 = uigridlayout(app.OverviewTab);
            app.ProgressGauge = uigauge(app.GridLayout3, 'linear');
            app.Axis1Gauge = uigauge(app.GridLayout3, 'linear');
            app.Axis1Gauge.Limits = [-180 180];
            app.Axis1Gauge.MajorTicks = [-180 -90 0 90 180];
            app.Axis2Gauge = uigauge(app.GridLayout3, 'linear');
            app.Axis2Gauge.Limits = [0 360];
            app.Axis2Gauge.MajorTicks = [0 90 180 270 360];
            app.Axis3Gauge = uigauge(app.GridLayout3, 'linear');
            app.Axis3Gauge.Limits = [-180 180];
            app.Axis3Gauge.MajorTicks = [-180 -90 0 90 180];
            app.ProgressLabel = uilabel(app.GridLayout3);
            app.ProgressLabel.Text = 'Progress';
            app.Axis1Label = uilabel(app.GridLayout3);
            app.Axis1Label.Text = 'Axis 1';
            app.Axis2Label = uilabel(app.GridLayout3);
            app.Axis2Label.Text = 'Axis 2';
            app.Axis3Label = uilabel(app.GridLayout3);
            app.Axis3Label.Text = 'Axis 3';
            app.StatusLampLabel = uilabel(app.GridLayout3);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.GridLayout3);
            app.StartButton = uibutton(app.GridLayout3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout3, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.MeasurementsTab = uitab(app.TabGroup);
            app.MeasurementsTab.Title = 'Measurements';
            app.GridLayout5 = uigridlayout(app.MeasurementsTab);
            app.MeasurementsAxes = uiaxes(app.GridLayout5);
            title(app.MeasurementsAxes, 'Measurements')
            xlabel(app.MeasurementsAxes, 'Position')
            ylabel(app.MeasurementsAxes, 'S21 (dB)')
            zlabel(app.MeasurementsAxes, 'Z')
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.FrequencyDropDownLabel = uilabel(app.GridLayout6);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.GridLayout6);
            app.FrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyDropDownValueChanged, true);
            app.SliceAxisDropDownLabel = uilabel(app.GridLayout6);
            app.SliceAxisDropDownLabel.Enable = 'off';
            app.SliceAxisDropDownLabel.Text = 'Slice Axis';
            app.SliceAxisDropDown = uidropdown(app.GridLayout6);
            app.SliceAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @SliceAxisDropDownValueChanged, true);
            app.SliceAxisDropDown.Enable = 'off';
            app.SlicePositionDropDownLabel = uilabel(app.GridLayout6);
            app.SlicePositionDropDownLabel.Enable = 'off';
            app.SlicePositionDropDownLabel.Text = 'Slice Position';
            app.SlicePositionDropDown = uidropdown(app.GridLayout6);
            app.SlicePositionDropDown.ValueChangedFcn = createCallbackFcn(app, @SlicePositionDropDownValueChanged, true);
            app.SlicePositionDropDown.Enable = 'off';
            app.WorkingLabel = uilabel(app.GridLayout6);
            app.WorkingLabel.Visible = 'off';
            app.WorkingLabel.Text = 'Working...';
            app.EstTimeRemainingLabel = uilabel(app.GridLayout6);
            app.EstTimeRemainingLabel.Visible = 'off';
            app.EstTimeRemainingLabel.Text = 'Calculating...';
            app.NormalizeButton = uibutton(app.GridLayout6, 'state');
            app.NormalizeButton.ValueChangedFcn = createCallbackFcn(app, @NormalizeButtonValueChanged, true);
            app.NormalizeButton.Text = 'Normalize';
            app.LogsTab = uitab(app.TabGroup);
            app.LogsTab.Title = 'Logs';
            app.GridLayout7 = uigridlayout(app.LogsTab);
            app.LogBox = uitextarea(app.GridLayout7);
            app.LogBox.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunJobs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end