classdef VNAApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        GridLayout2        matlab.ui.container.GridLayout
        GridLayout3        matlab.ui.container.GridLayout
        MinEditField       matlab.ui.control.NumericEditField
        MinEditFieldLabel  matlab.ui.control.Label
        MaxEditField       matlab.ui.control.NumericEditField
        MaxEditFieldLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Reference back to the main app
        el         % Event listener reference
        haveLoadedUIdefaults
    end
    
    methods (Access = private)
        function ec = setupStateChangeEventHandler(app)
            ec = app.CallingApp.VNA.getEventContainer();
            app.el = addlistener(ec, "Measurement", @(src, data) app.measurementHandler(src, data));
            app.haveLoadedUIdefaults = false;
        end

        % Called when VNA emits events
        function measurementHandler(app, eventSrc, eventData)
            % Note that the results are in the format from
            % HP8720::measure(), not the final format that is exported when
            % the measurements are all completed.

            results = eventData.Results;

            % results has:
            % startFreq
            % stopFreq
            % SCAL
            % REFP
            % REFV
            % freq
            % S21

            plot(app.UIAxes, results.freq, 20*log10(abs(results.S21)));
            if ~app.haveLoadedUIdefaults
                app.loadUIdefaults();
                app.haveLoadedUIdefaults = true;
            end
        end

        function updateYaxisLimits(app)
            min = app.MinEditField.Value;
            max = app.MaxEditField.Value;
            app.UIAxes.YLim = [min max];
        end

        function loadUIdefaults(app)
            % Load the defaults into UI controls based on the first data
            % returned from the VNA
            limits = app.UIAxes.YLim;
            app.MinEditField.Value = limits(1);
            app.MaxEditField.Value = limits(2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;

            % Set up event handlers
            app.setupStateChangeEventHandler();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                delete(app.el);
                app.CallingApp.closingVNAApp();
            catch
                % Do nothing.
            end
            delete(app);
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            app.updateYaxisLimits();
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            app.updateYaxisLimits();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'VNA: S21')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'S21 (dB)')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.MaxEditFieldLabel = uilabel(app.GridLayout3);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MinEditFieldLabel = uilabel(app.GridLayout3);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VNAApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end