classdef ViewResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ClearLogButton             matlab.ui.control.Button
        RecentFilesLabel           matlab.ui.control.Label
        FileLog                    matlab.ui.control.ListBox
        Image                      matlab.ui.control.Image
        ImportFileButton           matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        GainvsFrequencyTab         matlab.ui.container.Tab
        Frequency_CB               matlab.ui.control.CheckBox
        AzimuthAngleDropDown       matlab.ui.control.DropDown
        AzimuthAngleDropDownLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        GainvsAngleTab             matlab.ui.container.Tab
        Angle_CB                   matlab.ui.control.CheckBox
        FrequencyGHzDropDown       matlab.ui.control.DropDown
        FrequencyGHzDropDownLabel  matlab.ui.control.Label
        UIAxes_2                   matlab.ui.control.UIAxes
        AxialRatioTab              matlab.ui.container.Tab
        UIAxes_3                   matlab.ui.control.UIAxes
        SGHTab                     matlab.ui.container.Tab
        ViewImportedFilesButton    matlab.ui.control.StateButton
        ImportSGHFileButton        matlab.ui.control.Button
        UIAxes_4                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Results
        PathName

        AR %axial results
        APN %axial path name

        SGHR %standard gain horn results
        SGHPN %standard gain horn path

        AUTR    %antenna results
        AUTPN   %antenna path name

        files %struct containing log of successfully uploaded files
        info %struct for various data for calibrated gain
        counter %counter for calibrated gain plots
    end
    
    
    methods (Access = private) 
        function [measurement] = extractMeasurement1D(~, results, freq)
            % EXTRACTMEASUREMENT1D extracts simple 1D measurement results for
            % analysis.

            f = find([results.data.freq] == freq);
            if isempty(f)
                error("No match in results for specified frequency (%d)", freq);
            end

            steps = results.data(f).steps;
            if length(steps(1).pos) > 1
                error("Expected only one dimension in results");
            end

            % Frequency selected by the user
            measurement.freq = freq;

            % Intermediate stuff
            actualAxesCt = length(results.data(1).steps(1).actualPos);
            stepCt = length(steps);
            actualPositions = [steps.actualPos];

            % Specific data points
            measurement.axisNames = results.meta.axisNames;
            measurement.position = [steps.pos];
            measurement.actualPosition = reshape(actualPositions, [actualAxesCt, stepCt])';
            measurement.S21 = [steps.S21];
        end

        function [measurement] = extractMeasurement1DFreq(~, results, pos)
            % EXTRACTMEASUREMENT1D extracts all frequency data from the provided
            % one-dimensional results at a specified position.

            if ~ any([results.data(1).steps.pos] == pos)
                error("No match in results for specified position (%d)", pos);
            end

            if length(results.data(1).steps(1).pos) > 1
                error("Expected only one dimension in results");
            end

            % Position selected by the user
            measurement.position = pos;

            % Specific data points
            measurement.freqs = [results.data.freq];

            % For each frequency...
            for f = 1:length(results.data)
                steps = results.data(f).steps;
                p = find([steps.pos] == pos);
                if isempty(p) % Just in case...
                    error("No patch in results for specified position (%d)", pos);
                end

                measurement.S21(f) = steps(p).S21;
                measurement.actualPosition(f, :) = steps(p).actualPos;
            end

            measurement.axisNames = results.meta.axisNames;
        end

        function plot_az(app,gainpn,gainr)
            if ~(nargin == 1)
                app.Results = gainr;
                app.PathName = gainpn;

                if isempty(app.Results) == 1
                    uiwait(msgbox("Import file before continuing.","Error","modal")); %uiwait waits until okay is clicked
                    app.Frequency_CB.Value = 1;
                else
                    file = fullfile(gainpn, gainr);
                    load(file, "results")

                    dd = erase(app.AzimuthAngleDropDown.Value,"°"); %removes degree symbol from dropdown "Value" and sets to dd
                    inputazmth = str2double(dd); %dd needs to be converted to double

                    az_measuredResults = extractMeasurement1DFreq(app,results,inputazmth);

                    az_mag = abs(az_measuredResults.S21);
                    az_db = 20*log10(az_mag);
                    max_db = max(az_db);
                    norm_az_db = az_db - max_db;

                    if app.Frequency_CB.Value == 1
                        ylabel(app.UIAxes,'Normalized Gain (dB)','FontWeight','bold');
                        plot(app.UIAxes,az_measuredResults.freqs/1e9,norm_az_db)
                    else
                        ylabel(app.UIAxes,'Magnitude (dB)','FontWeight','bold');
                        plot(app.UIAxes,az_measuredResults.freqs/1e9,az_db)
                    end
                end
            else
                plot_az(app,app.PathName,app.Results)
            end
        end

        function plot_fr(app,gainpn,gainr)
            if ~(nargin == 1)
                app.Results = gainr;
                app.PathName = gainpn;

                if isempty(app.Results) == 1
                    uiwait(msgbox("Import file before continuing.","Error","modal"));
                    app.Angle_CB.Value = 1;
                else
                    cb = app.Angle_CB.Value;
                    

                    file = fullfile(gainpn, gainr);
                    load(file, "results")


                    dd = app.FrequencyGHzDropDown.Value;
                    inputfreq = str2double(dd) * 10^9; %converts to double and multiplies value by 10^9
                    fr_measuredResults = extractMeasurement1D(app,results,inputfreq);

                    fr_mag = abs(fr_measuredResults.S21);
                    fr_db = 20*log10(fr_mag);
                    max_db = max(fr_db);
                    norm_fr_db = fr_db - max_db;

                    if cb == 1
                        ylabel(app.UIAxes_2,'Normalized Gain (dB)','FontWeight','bold');
                        plot(app.UIAxes_2,fr_measuredResults.position,norm_fr_db)
                    else
                        ylabel(app.UIAxes_2,'Magnitude (dB)','FontWeight','bold');
                        plot(app.UIAxes_2,fr_measuredResults.position,fr_db)
                    end
                end
            else
                plot_fr(app,app.PathName,app.Results)
            end
        end

        function plot_axial(app,axialpn,axialr)
            if isempty(axialr)
                msgbox("Import file before continuing.","Error","modal");   %placeholder
            else
                file = fullfile(axialpn,axialr);
                load(file, "results")

                [m1] = extractMeasurement1DFreq(app,results,0);
                ar = size(m1.freqs);
                for i = 1:numel(m1.freqs)
                    [m2] = extractMeasurement1D(app,results,m1.freqs(i));
                    s21 = 20*log10(abs(m2.S21));
                    ar(i) = max(s21) - min(s21);
                end
                freq1 = m1.freqs;
                plot(app.UIAxes_3,freq1/1e9,ar,'linewidth',1);
            end

        end

        function errlog = error_handle(app,data,path)
            tab = app.TabGroup.SelectedTab; %tabname
            
            %check if file exists
            if data == 0
                errlog = "No file selected.";
                return
            end

            %check if file is .mat
            [~,~,ext] = fileparts(data);
            if ~isequal(ext,'.mat')
                errlog = "Please select a .mat file.";
                return
            end

            %check if imported data corresponds to correct tab
            file = fullfile(path,data);
            load(file, "results")
            if tab == app.TabGroup.Children(1) || tab == app.TabGroup.Children(2) || tab == app.TabGroup.Children(4)
                if length(results.data(1).steps) == 36
                    errlog = "Please upload a file that corresponds to the selected tab.";
                    return
                end
                errlog = 0; %no error
                return
            end

            if tab == app.TabGroup.Children(3)
                if length(results.data(1).steps) == 36
                    errlog = 0;
                    return
                end
                errlog = "Please upload axial ratio results to this tab.";
                return
            end

            errlog = "Error."; %catch-all

        end
        
        function codelog = updateLog(app,path,name,tab)
            if nargin == 1
                codelog = flip(app.files);
            else
                app.RecentFilesLabel.Visible = "on";
                app.FileLog.Visible = "on";
                app.ClearLogButton.Visible = "on";

                entry = (length(app.files) + 1);
                app.files(entry).tab = string(tab);

                if isstruct(name)
                    app.files(entry).path = path;
                    app.files(entry).name = name;
                else
                    app.files(entry).path = string(path);
                    app.files(entry).name = string(name);
                end

                %create list entries for log
                if ~isstruct(app.files(entry).name) == 1
                    app.files(entry).listing = erase(app.files(entry).name,".mat");
                else
                    app.files(entry).listing = "AUT & SGH" + " [" + app.counter + "]";
                    app.counter = app.counter + 1;
                end

                cl = flip(app.files);
                app.FileLog.Items = horzcat(cl.listing);
            end

        end
        
        function logplot(app,data,index)
            path = data(index).path;
            name = data(index).name;

            if (data(index).tab == "1") %or 2 technically
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                setDropDown(app,path,name)
                plot_az(app,path,name);
                plot_fr(app,path,name);
            elseif data(index).tab == "3"
                app.TabGroup.SelectedTab = app.TabGroup.Children(3);
                plot_axial(app,path,name)
            elseif data(index).tab == "4"
                app.TabGroup.SelectedTab = app.TabGroup.Children(4);
                app.AUTPN = path.AUTpath;
                app.AUTR = name.AUTname;
                app.SGHPN = path.SGHpath;
                app.SGHR = name.SGHname;

                comparefiles(app);
                calibrategain(app);
            else
                uialert(app.UIFigure,"Error when trying to replot data from file log.",'Error');
            end
        end
        
        function setDropDown(app,gainpn,gainr)
            file = fullfile(gainpn, gainr);
            load(file, "results");

            %azimuth angle
            angle_str = string([results.data(1).steps.pos]); %converts pos values to string values
            az_angle_final = strcat(angle_str, '°'); %concatenates angle_str with degree symbol
            app.AzimuthAngleDropDown.Items = {az_angle_final{:}}; %#ok<CCAT1> %sets items of dropdown menu to az_angle_final
            app.AzimuthAngleDropDown.Value = az_angle_final{ceil(length(az_angle_final)/2)}; %sets initial value to middle postion

            %frequency
            frq_str = string([results.data.freq] ./ (10^9));
            app.FrequencyGHzDropDown.Items = {frq_str{:}}; %sets frequency strings to items in drop down menu
            app.FrequencyGHzDropDown.Value = frq_str{1}; %sets initial frq drop down value to first frequency

        end

        function calibrategain(app,~)
            min1 = app.info.min1;
            max1 = app.info.max1;
            ant_db = app.info.ant_db;
            antdata = app.info.antdata;
            sgh_db = app.info.sgh_db;
            sghdata = app.info.sghdata;

            %obtain gain difference
            gain_diff = ant_db - sgh_db;

            % use avg function to get SGH data
            avg = (min1 + max1)/2;
            if ((avg > 1.7) && (avg < 2.6))
                trace = "15cm";
            elseif ((avg > 2.6) && (avg < 3.9))
                trace = "10cm";
            elseif ((avg > 3.9) && (avg < 5.8))
                trace = "6cm";
            elseif ((avg > 5.8) && (avg < 8.2))
                trace = "4.75cm";
            elseif ((avg > 8.2) && (avg < 12.4))
                trace = "3.2cm";
            elseif ((avg > 12.4) && (avg < 18))
                trace = "1.8cm";
            else
                uialert(app.UIFigure,"Error when determining which SGH to use.", 'Error')
                return
            end

            antfile = fullfile(app.AUTPN, app.AUTR);
            load(antfile, "results");

            geometry = sgh_antenna_geometry(trace);
            steps = length(results.data);
            f = linspace(min1, max1, steps);
            frq = f*1e9;
            [g,~] = calc_gain(frq, geometry);
            horn_db = 10*log10(g);
            clbrgain = horn_db + gain_diff;

            hold(app.UIAxes_4, "on");
            if nargin == 1
                cla(app.UIAxes_4)
                plot(app.UIAxes_4,f, clbrgain, 'Color', 'black');
                legend(app.UIAxes_4, 'off')
                app.ViewImportedFilesButton.Enable = "on";
            else
                cla(app.UIAxes_4)
                plot(app.UIAxes_4, antdata.freqs/1e9, ant_db, 'Color','red');
                plot(app.UIAxes_4, sghdata.freqs/1e9, sgh_db, 'Color', 'blue');
                plot(app.UIAxes_4,f, clbrgain, 'Color', 'black');
                legend(app.UIAxes_4, 'AUT', 'SGH', 'Calibrated Gain')
            end
        end
        
        function comparefiles(app)
            %check if frequencies match
            antfile = fullfile(app.AUTPN, app.AUTR);
            load(antfile, "results");

            antdata = extractMeasurement1DFreq(app,results,str2double("0"));
            ant_mag = abs(antdata.S21);
            ant_db = 20*log10(ant_mag);
            min1 = results.meta.startFreq/1e9;
            max1 = results.meta.stopFreq/1e9;

            sghfile = fullfile(app.SGHPN, app.SGHR);
            load(sghfile, "results");

            sghdata = extractMeasurement1DFreq(app,results,str2double("0"));
            sgh_mag = abs(sghdata.S21);
            sgh_db = 20*log10(sgh_mag);
            min2 = results.meta.startFreq/1e9;
            max2 = results.meta.stopFreq/1e9;

            if (~(isequal(min1,min2))) || (~(isequal(max1,max2)))
                msg1 = "Frequency Mismatch";
                msg2 = "The AUT file starts at " + min1 + " GHz and ends at " + max1 + " GHz.";
                msg3 = "The SGH file starts at " + min2 + " GHz and ends at " + max2 + " GHz.";
                msg = [msg1 msg2 msg3];
                title = "Error";
                selection = uiconfirm(app.UIFigure, msg, title, ...
                    "Options",{'Reupload Antenna File', 'Reupload SGH file','Close All'},"Icon","error");
                switch selection
                    case 'Reupload Antenna File'
                        [app.AUTR, app.AUTPN] = uigetfile('*.mat');
                    case 'Reupload SGH file'
                        [app.SGHR, app.SGHPN] = uigetfile('*.mat');
                    otherwise
                        delete(app) %close the whole app. idk what to do at this point
                end
                comparefiles(app); %calls itself until frequencies match
            end
            app.info.min1 = min1;
            app.info.max1 = max1;
            app.info.ant_db = ant_db;
            app.info.antdata = antdata;
            app.info.sgh_db = sgh_db;
            app.info.sghdata = sghdata;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Side Bar
            app.RecentFilesLabel.Visible = "off";
            app.FileLog.Visible = "off";
            app.ClearLogButton.Visible = "off";
            app.files = struct("name", [], "path", [], "tab", [], "listing", []);
            app.counter = 1;
            
            %Tab 1
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';

            title(app.UIAxes,'');
            xlabel(app.UIAxes,'Frequency (GHz)','FontWeight','bold','FontSize',12);
            ylabel(app.UIAxes,'Gain (dB)','FontWeight','bold','FontSize',12);
            box(app.UIAxes, 'on');

            app.Frequency_CB.Value = 1;

            %Tab 2
            app.UIAxes_2.XGrid = 'on';
            app.UIAxes_2.YGrid = 'on';

            title(app.UIAxes_2,'');
            xlabel(app.UIAxes_2,'Azimuth Angle (Degrees)','FontWeight','bold','FontSize',12);
            ylabel(app.UIAxes_2,'Gain (dB)','FontWeight','bold','FontSize',12);
            box(app.UIAxes_2, 'on');

            app.Angle_CB.Value = 1;

            %Tab 3
            app.UIAxes_3.XGrid = 'on';
            app.UIAxes_3.YGrid = 'on';

            title(app.UIAxes_3, '');
            xlabel(app.UIAxes_3,'Frequency (GHz)','FontWeight','bold','FontSize',12);
            ylabel(app.UIAxes_3,'Axial Ratio (dB)','FontWeight','bold','FontSize',12);
            box(app.UIAxes_3, 'on');

            %Tab 4
            app.UIAxes_4.XGrid = 'on';
            app.UIAxes_4.YGrid = 'on';

            title(app.UIAxes_4, '');
            xlabel(app.UIAxes_4,'Frequency (GHz)','FontWeight','bold','FontSize',12);
            ylabel(app.UIAxes_4,'Gain (dBi)','FontWeight','bold','FontSize',12);
            box(app.UIAxes_4, 'on');

            app.ImportSGHFileButton.Enable = "off";
            app.ViewImportedFilesButton.Enable = "off";
        end

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            tab = app.TabGroup.SelectedTab;
            switch tab
                case app.TabGroup.Children(1)
                    [gainr, gainpn] = uigetfile('*.mat');
                    errlog = error_handle(app,gainr,gainpn);
                case app.TabGroup.Children(2)
                    [gainr, gainpn] = uigetfile('*.mat');
                    errlog = error_handle(app,gainr,gainpn);
                case app.TabGroup.Children(3)
                    [axialr, axialpn] = uigetfile('*.mat');
                    errlog = error_handle(app,axialr,axialpn);
                case app.TabGroup.Children(4)
                    [antr, antpn] = uigetfile('*.mat');
                    errlog = error_handle(app,antr,antpn);
                otherwise
                    uialert(app.UIFigure,"See ImportFileButtonPushed.", 'Error')
            end

            figure(app.UIFigure) %work-around for when app minimizes when file is imported

            if  ~isequal(errlog,0)
                uialert(app.UIFigure,errlog,'Error');
            else
                if tab == app.TabGroup.Children(1) || tab == app.TabGroup.Children(2)
                    setDropDown(app,gainpn,gainr)
                    plot_az(app,gainpn,gainr);
                    plot_fr(app,gainpn,gainr);
                    updateLog(app,gainpn,gainr,"1");
                elseif tab == app.TabGroup.Children(3)
                    plot_axial(app,axialpn,axialr);
                    updateLog(app,axialpn,axialr,"3");
                elseif tab == app.TabGroup.Children(4)
                    app.AUTR = antr;
                    app.AUTPN = antpn;
                    app.ImportSGHFileButton.Enable = "on";
                else
                    uialert(app.UIFigure,"Unknown Tab Selected. Somehow..", 'Error')
                end
            end
        end

        % Value changed function: AzimuthAngleDropDown
        function AzimuthAngleDropDownValueChanged(app, event)
            plot_az(app);
        end

        % Value changed function: Frequency_CB
        function Frequency_CBValueChanged(app, event)
            plot_az(app);
        end

        % Value changed function: FrequencyGHzDropDown
        function FrequencyGHzDropDownValueChanged(app, event)
            plot_fr(app);
        end

        % Value changed function: Angle_CB
        function Angle_CBValueChanged(app, event)
            plot_fr(app);
        end

        % Button pushed function: ImportSGHFileButton
        function ImportSGHFileButtonPushed(app, event)
            [app.SGHR, app.SGHPN] = uigetfile('*.mat');
            errlog = error_handle(app,app.SGHR,app.SGHPN);
            if  ~isequal(errlog,0)
                uialert(app.UIFigure,errlog,'Error');
            else
                comparefiles(app);

                pathstr = struct("AUTpath",app.AUTPN,"SGHpath",app.SGHPN);
                namestr = struct("AUTname",app.AUTR,"SGHname",app.SGHR);
                updateLog(app,pathstr,namestr,"4")

                calibrategain(app);
                app.ImportSGHFileButton.Enable = "off";
                figure(app.UIFigure);
            end
        end

        % Double-clicked callback: FileLog
        function FileLogDoubleClicked(app, event)
            sel = find(ismember(app.FileLog.Items,app.FileLog.Value)); %get index of selected value
            codelog = updateLog(app);
            logplot(app,codelog,sel)
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
            app.files = struct([]);
            app.RecentFilesLabel.Visible = "off";
            app.FileLog.Visible = "off";
            app.ClearLogButton.Visible = "off";
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            tab = app.TabGroup.SelectedTab;
            if tab == app.TabGroup.Children(4)
                %cla(app.UIAxes_4)
                app.ImportFileButton.Text = 'Import AUT File';
            else
                app.ImportFileButton.Text = 'Import File';
            end
        end

        % Value changed function: ViewImportedFilesButton
        function ViewImportedFilesButtonValueChanged(app, event)
            state = app.ViewImportedFilesButton.Value;
            if state == 1
                app.ViewImportedFilesButton.Text = 'Hide Imported Files';
                calibrategain(app,1)
            else
                app.ViewImportedFilesButton.Text = 'View Imported Files';
                calibrategain(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GainvsFrequencyTab = uitab(app.TabGroup);
            app.GainvsFrequencyTab.Title = 'Gain vs. Frequency';
            app.UIAxes = uiaxes(app.GainvsFrequencyTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AzimuthAngleDropDownLabel = uilabel(app.GainvsFrequencyTab);
            app.AzimuthAngleDropDownLabel.Text = 'Azimuth Angle';
            app.AzimuthAngleDropDown = uidropdown(app.GainvsFrequencyTab);
            app.AzimuthAngleDropDown.Items = {'0'};
            app.AzimuthAngleDropDown.ValueChangedFcn = createCallbackFcn(app, @AzimuthAngleDropDownValueChanged, true);
            app.AzimuthAngleDropDown.Value = '0';
            app.Frequency_CB = uicheckbox(app.GainvsFrequencyTab);
            app.Frequency_CB.ValueChangedFcn = createCallbackFcn(app, @Frequency_CBValueChanged, true);
            app.Frequency_CB.Text = 'Normalize';
            app.GainvsAngleTab = uitab(app.TabGroup);
            app.GainvsAngleTab.Title = 'Gain vs. Angle';
            app.UIAxes_2 = uiaxes(app.GainvsAngleTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.FrequencyGHzDropDownLabel = uilabel(app.GainvsAngleTab);
            app.FrequencyGHzDropDownLabel.Text = 'Frequency (GHz)';
            app.FrequencyGHzDropDown = uidropdown(app.GainvsAngleTab);
            app.FrequencyGHzDropDown.Items = {};
            app.FrequencyGHzDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyGHzDropDownValueChanged, true);
            app.FrequencyGHzDropDown.Value = {};
            app.Angle_CB = uicheckbox(app.GainvsAngleTab);
            app.Angle_CB.ValueChangedFcn = createCallbackFcn(app, @Angle_CBValueChanged, true);
            app.Angle_CB.Text = 'Normalize';
            app.AxialRatioTab = uitab(app.TabGroup);
            app.AxialRatioTab.Title = 'Axial Ratio';
            app.UIAxes_3 = uiaxes(app.AxialRatioTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.SGHTab = uitab(app.TabGroup);
            app.SGHTab.Title = 'SGH';
            app.UIAxes_4 = uiaxes(app.SGHTab);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.ImportSGHFileButton = uibutton(app.SGHTab, 'push');
            app.ImportSGHFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSGHFileButtonPushed, true);
            app.ImportSGHFileButton.Text = 'Import SGH File';
            app.ViewImportedFilesButton = uibutton(app.SGHTab, 'state');
            app.ViewImportedFilesButton.ValueChangedFcn = createCallbackFcn(app, @ViewImportedFilesButtonValueChanged, true);
            app.ViewImportedFilesButton.Text = 'View Imported Files';
            app.ImportFileButton = uibutton(app.UIFigure, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'csunlogo.png';
            app.FileLog = uilistbox(app.UIFigure);
            app.FileLog.DoubleClickedFcn = createCallbackFcn(app, @FileLogDoubleClicked, true);
            app.RecentFilesLabel = uilabel(app.UIFigure);
            app.RecentFilesLabel.Text = 'Recent Files';
            app.ClearLogButton = uibutton(app.UIFigure, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViewResults

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end